classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        CalculatePotentialTab          matlab.ui.container.Tab
        Panel                          matlab.ui.container.Panel
        Panel_2                        matlab.ui.container.Panel
        SpecifyLJParametersLabel       matlab.ui.control.Label
        maxrEditField                  matlab.ui.control.NumericEditField
        maxrEditFieldLabel             matlab.ui.control.Label
        minrEditField                  matlab.ui.control.NumericEditField
        minrEditFieldLabel             matlab.ui.control.Label
        Z2EditField                    matlab.ui.control.NumericEditField
        Z2EditFieldLabel               matlab.ui.control.Label
        Z1EditField                    matlab.ui.control.NumericEditField
        Z1EditFieldLabel               matlab.ui.control.Label
        SpecifyCoulombParametersLabel  matlab.ui.control.Label
        PotentialTypeDropDown          matlab.ui.control.DropDown
        PotentialTypeDropDownLabel     matlab.ui.control.Label
        SelectedPathEditField_4        matlab.ui.control.EditField
        SelectedPathEditField_4Label   matlab.ui.control.Label
        SelectInputFileButton          matlab.ui.control.Button
        OrSelectInputFileLabel         matlab.ui.control.Label
        SpecifyWheretoSavePotentialDataLabel  matlab.ui.control.Label
        SelectedPathEditField_3        matlab.ui.control.EditField
        SelectedPathEditField_3Label   matlab.ui.control.Label
        SelectPotentialPathButton      matlab.ui.control.Button
        SpecifyWheretoSaveInputFileLabel  matlab.ui.control.Label
        SelectedPathEditField_2        matlab.ui.control.EditField
        SelectedPathEditField_2Label   matlab.ui.control.Label
        SelectInputPathButton          matlab.ui.control.Button
        ExecuteButton                  matlab.ui.control.Button
        WriteInputFileButton           matlab.ui.control.Button
        TabGroup2                      matlab.ui.container.TabGroup
        LINEARTab                      matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        LOGTab                         matlab.ui.container.Tab
        UIAxes_2                       matlab.ui.control.UIAxes
        FitPotentialTab                matlab.ui.container.Tab
        TabGroup2_2                    matlab.ui.container.TabGroup
        LINEARTab_2                    matlab.ui.container.Tab
        UIAxes_3                       matlab.ui.control.UIAxes
        LOGTab_2                       matlab.ui.container.Tab
        UIAxes_4                       matlab.ui.control.UIAxes
        Panel_4                        matlab.ui.container.Panel
        SpecifyLJParametersLabel_2     matlab.ui.control.Label
        Panel_3                        matlab.ui.container.Panel
        minZ2EditField                 matlab.ui.control.NumericEditField
        minZ2EditFieldLabel            matlab.ui.control.Label
        maxZ2EditField                 matlab.ui.control.NumericEditField
        maxZ2EditFieldLabel            matlab.ui.control.Label
        toleranceEditField             matlab.ui.control.NumericEditField
        toleranceEditFieldLabel        matlab.ui.control.Label
        maxrEditField_2                matlab.ui.control.NumericEditField
        maxrEditField_2Label           matlab.ui.control.Label
        minrEditField_2                matlab.ui.control.NumericEditField
        minrEditField_2Label           matlab.ui.control.Label
        Z1EditField_2                  matlab.ui.control.NumericEditField
        Z1EditField_2Label             matlab.ui.control.Label
        SpecifyCoulombParametersLabel_2  matlab.ui.control.Label
        FitTypeDropDown                matlab.ui.control.DropDown
        FitTypeDropDownLabel           matlab.ui.control.Label
        OrSelectYourOwnPotentialDataFileLabel  matlab.ui.control.Label
        DataLocationEditField          matlab.ui.control.EditField
        DataLocationEditFieldLabel     matlab.ui.control.Label
        SelectedPathEditField          matlab.ui.control.EditField
        SelectedPathEditFieldLabel     matlab.ui.control.Label
        SelectPotentialFileButton      matlab.ui.control.Button
        ExecuteButton_2                matlab.ui.control.Button
        CalculateScatteringIntegralsTab  matlab.ui.container.Tab
        CalcuateTransportCoefficientsTab  matlab.ui.container.Tab
        CalcuateDSMCCoefficientsTab    matlab.ui.container.Tab
    end

    %these are properties that I utilize across different callbacks
    properties (Access = public)
        matfile % Description
        directory
        DataSetName
        usrnumber
        selpath
        selpath2
        selpath3
        selpath4
     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TabGroup.FontName="Courier";
            app.TabGroup.FontWeight = 'bold';
            app.TabGroup2.FontName="Courier";
            app.TabGroup2.FontWeight = 'bold';
        end

        % Button pushed function: ExecuteButton_2
        function ExecuteButton_2Pushed(app, event)
            %creates input file with user-specified parameters
            %saves input file in user specified directory
            % and executes run_calcpotential, plots data, pushes data
            %location to next tab
            z1 = app.Z1EditField_2.Value;
            minr = app.minrEditField_2.Value;
            maxr = app.maxrEditField_2.Value;
            tol = app.toleranceEditField.Value;
            minz2 = app.minZ2EditField.Value;
            maxz2 = app.maxZ2EditField.Value;
            filename  = [app.selpath '/fitinputfile.m'];
            inputfile=fopen(filename,'w');
            fprintf(inputfile,'Potential_Type = "%s";\n',app.FitTypeDropDown.Value);
            fprintf(inputfile, 'Z1 = %i;\n',z1);
            fprintf(inputfile, 'minR = %f;\n',minr);
            fprintf(inputfile, 'maxR = %f;\n',maxr);
            fprintf(inputfile,'tol = %e;\n',tol);
            fprintf(inputfile, 'minZ2 = %i;\n',minz2);
            fprintf(inputfile, 'maxZ2 = %i;\n',maxz2);
            fclose(inputfile);
            
            addpath('./src')
            addpath('../src')
            datalocation = [app.selpath '/coulomb_data.csv'];
            t = readmatrix(datalocation);
            myfit = run_fitpotential(app.selpath);
            outputfilename  = [app.selpath '/fitoutputfile.m'];
            outputfile=fopen(outputfilename,'w');
            fprintf(outputfile,'Potential_Type = "%s";\n',app.FitTypeDropDown.Value);
            fprintf(outputfile,'Params = %s;\n',coeffvalues(myfit));
            fprintf(outputfile, 'minR = %f;\n',minr);
            fprintf(outputfile, 'maxR = %f;\n',maxr);
            fprintf(outputfile, 'Tol = %e;\n',tol);
            fprintf(outputfile, 'minZ2 = %i;\n',minz2);
            fprintf(outputfile, 'maxZ2 = %i;\n',maxz2);
            fclose(outputfile);
            xvals = nonzeros(t(:,1).*((minr <= t(:,1)) & (t(:,1) <= maxr)));
            yvals = nonzeros(t(:,2).*((minr <= t(:,1)) & (t(:,1) <= maxr)));
            plot(app.UIAxes_3,myfit,xvals,yvals);
            plot(app.UIAxes_4,myfit,xvals,yvals);
            yscale(app.UIAxes_4,'log');
            xscale(app.UIAxes_4,'linear');

        end

        % Button pushed function: SelectPotentialFileButton
        function SelectPotentialFileButtonPushed(app, event)
            %allows you to select input file from directory
            app.selpath  = uigetdir;
            app.SelectedPathEditField.Value = app.selpath;
            app.DataLocationEditField.Value = app.selpath;
        end

        % Callback function
        function SelectInputPathButtonPushed(app, event)
            %allows you to select where input file will save
            app.selpath2  = uigetdir;
            app.SelectedPathEditField_2.Value = app.selpath2;
        end

        % Callback function
        function WriteInputFileButtonPushed(app, event)
            %creates input file with user-specified parameters
            %saves input file in user specified directory
            z1 = app.Z1EditField.Value;
            z2 = app.Z2EditField.Value;
            minr = app.minrEditField.Value;
            maxr = app.maxrEditField.Value;
            filename  = [app.selpath2 '/inputfile.m'];
            inputfile=fopen(filename,'w');
            fprintf(inputfile, 'Z1 = %i;\n',z1);
            fprintf(inputfile, 'Z2 = %i;\n',z2);
            fprintf(inputfile, 'minR = %f;\n',minr);
            fprintf(inputfile, 'maxR = %f;\n',maxr);
            fclose(inputfile);
            app.SelectedPathEditField_4.Value = filename;
        end

        % Callback function
        function SelectPotentialPathButtonPushed(app, event)
            %allows user to specify where potential data will save
            app.selpath3  = uigetdir;
            app.SelectedPathEditField_3.Value = app.selpath3;
        end

        % Callback function
        function SelectInputFileButtonPushed(app, event)
            %allows user to select their own input file
            [baseName, folder] = uigetfile();
            app.selpath4 = fullfile(folder, baseName);
            app.SelectedPathEditField_4.Value = app.selpath4;
        end

        % Value changed function: PotentialTypeDropDown
        function PotentialTypeDropDownValueChanged(app, event)
            value = app.PotentialTypeDropDown.Value;
            % app.testvalEditField.Value = size(app.PotentialTypeDropDown.Value);
            % if app.PotentialTypeDropDown.Value == 'Coulomb'
            %     app.Panel.Visible='on'; % Set the visibility of the panel to 'on'
            % % elseif app.PotentialTypeDropDown.Value == 'LennardJones'
            % %     app.Panel_2.Visible='on'; % Set the visibility of the panel to 'on'
            % else 
            %     app.Panel.Visible='off';
            %     app.Panel_2.Visible='off';
            %     app.testvalEditField.Value = app.PotentialTypeDropDown.Value;
            % end


        end

        % Clicked callback: PotentialTypeDropDown
        function PotentialTypeDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            if strcmp(app.PotentialTypeDropDown.Value,'Coulomb')
                app.Panel.Visible='on'; % Set the visibility of the panel to 'on'
                app.Panel_2.Visible='off';
            elseif strcmp(app.PotentialTypeDropDown.Value,'Lennard-Jones')
                app.Panel_2.Visible='on'; % Set the visibility of the panel to 'on'
                app.Panel.Visible='on';
            else 
                app.Panel.Visible='off';
                app.Panel_2.Visible='off';
            end
        end

        % Callback function
        function WriteFitInputFileButtonPushed(app, event)
            %creates input file with user-specified parameters
            %saves input file in user specified directory
            z2 = app.Z2EditField.Value;
            minr = app.minrEditField.Value;
            maxr = app.maxrEditField.Value;
            filename  = [app.selpath2 '/inputfile.m'];
            inputfile=fopen(filename,'w');
            fprintf(inputfile, 'Z1 = %i;\n',z1);
            fprintf(inputfile, 'Z2 = %i;\n',z2);
            fprintf(inputfile, 'minR = %f;\n',minr);
            fprintf(inputfile, 'maxR = %f;\n',maxr);
            fclose(inputfile);
            app.SelectedPathEditField_4.Value = filename;
        end

        % Clicked callback: FitTypeDropDown
        function FitTypeDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            item = event.InteractionInformation.Item;
            if strcmp(app.FitTypeDropDown.Value,'Coulomb')
                app.Panel_3.Visible='on'; % Set the visibility of the panel to 'on'
                app.Panel_4.Visible='off';
            elseif strcmp(app.FitTypeDropDown.Value,'Lennard-Jones')
                app.Panel_4.Visible='on'; % Set the visibility of the panel to 'on'
                app.Panel_3.Visible='on';
            else 
                app.Panel_3.Visible='off';
                app.Panel_4.Visible='off';
            end
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            %executes run_calcpotential, plots data, pushes data
            %location to next tab
            addpath('../src')
            run_calcpotential(app.selpath3);
            datalocation = [app.selpath3 '/coulumb_data.csv'];
            t = readmatrix(datalocation);
            xvals = t(:,1);
            yvals = t(:,2);
            plot(app.UIAxes,xvals,yvals,'-o',MarkerFaceColor='b');
            plot(app.UIAxes_2,xvals,yvals,'-o',MarkerFaceColor='b');
            yscale(app.UIAxes_2,'log');
            xscale(app.UIAxes_2,'linear');
            app.DataLocationEditField.Value = app.selpath3;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CalculatePotentialTab = uitab(app.TabGroup);
            app.CalculatePotentialTab.Title = 'Calculate Potential';
            app.TabGroup2 = uitabgroup(app.CalculatePotentialTab);
            app.LINEARTab = uitab(app.TabGroup2);
            app.LINEARTab.Title = 'LINEAR';
            app.UIAxes = uiaxes(app.LINEARTab);
            title(app.UIAxes, 'Interatomic Potential')
            xlabel(app.UIAxes, 'Interatomic Distance (Å)')
            ylabel(app.UIAxes, 'Energy (eV)')
            zlabel(app.UIAxes, 'Z')
            app.LOGTab = uitab(app.TabGroup2);
            app.LOGTab.Title = 'LOG';
            app.UIAxes_2 = uiaxes(app.LOGTab);
            title(app.UIAxes_2, 'Interatomic Potential')
            xlabel(app.UIAxes_2, 'Interatomic Distance (Å)')
            ylabel(app.UIAxes_2, 'Energy (eV)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XScale = 'log';
            app.UIAxes_2.YScale = 'log';
            app.WriteInputFileButton = uibutton(app.CalculatePotentialTab, 'push');
            app.WriteInputFileButton.Text = 'Write Input File';
            app.ExecuteButton = uibutton(app.CalculatePotentialTab, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.SelectInputPathButton = uibutton(app.CalculatePotentialTab, 'push');
            app.SelectInputPathButton.Text = 'Select Input Path';
            app.SelectedPathEditField_2Label = uilabel(app.CalculatePotentialTab);
            app.SelectedPathEditField_2Label.Text = 'Selected Path';
            app.SelectedPathEditField_2 = uieditfield(app.CalculatePotentialTab, 'text');
            app.SpecifyWheretoSaveInputFileLabel = uilabel(app.CalculatePotentialTab);
            app.SpecifyWheretoSaveInputFileLabel.Text = 'Specify Where to Save Input File';
            app.SelectPotentialPathButton = uibutton(app.CalculatePotentialTab, 'push');
            app.SelectPotentialPathButton.Text = 'Select Potential Path';
            app.SelectedPathEditField_3Label = uilabel(app.CalculatePotentialTab);
            app.SelectedPathEditField_3Label.Text = 'Selected Path';
            app.SelectedPathEditField_3 = uieditfield(app.CalculatePotentialTab, 'text');
            app.SpecifyWheretoSavePotentialDataLabel = uilabel(app.CalculatePotentialTab);
            app.SpecifyWheretoSavePotentialDataLabel.Text = 'Specify Where to Save Potential Data';
            app.OrSelectInputFileLabel = uilabel(app.CalculatePotentialTab);
            app.OrSelectInputFileLabel.Text = 'Or Select Input File';
            app.SelectInputFileButton = uibutton(app.CalculatePotentialTab, 'push');
            app.SelectInputFileButton.Text = 'Select Input File';
            app.SelectedPathEditField_4Label = uilabel(app.CalculatePotentialTab);
            app.SelectedPathEditField_4Label.Text = 'Selected Path';
            app.SelectedPathEditField_4 = uieditfield(app.CalculatePotentialTab, 'text');
            app.PotentialTypeDropDownLabel = uilabel(app.CalculatePotentialTab);
            app.PotentialTypeDropDownLabel.Text = 'Potential Type';
            app.PotentialTypeDropDown = uidropdown(app.CalculatePotentialTab);
            app.PotentialTypeDropDown.Items = {'Select Potential', 'Coulomb', 'Lennard-Jones'};
            app.PotentialTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PotentialTypeDropDownValueChanged, true);
            app.PotentialTypeDropDown.ClickedFcn = createCallbackFcn(app, @PotentialTypeDropDownClicked, true);
            app.PotentialTypeDropDown.Value = 'Select Potential';
            app.Panel = uipanel(app.CalculatePotentialTab);
            app.Panel.Visible = 'off';
            app.SpecifyCoulombParametersLabel = uilabel(app.Panel);
            app.SpecifyCoulombParametersLabel.Text = 'Specify Coulomb Parameters';
            app.Z1EditFieldLabel = uilabel(app.Panel);
            app.Z1EditFieldLabel.Text = 'Z1';
            app.Z1EditField = uieditfield(app.Panel, 'numeric');
            app.Z2EditFieldLabel = uilabel(app.Panel);
            app.Z2EditFieldLabel.Text = 'Z2';
            app.Z2EditField = uieditfield(app.Panel, 'numeric');
            app.minrEditFieldLabel = uilabel(app.Panel);
            app.minrEditFieldLabel.Text = 'min r ';
            app.minrEditField = uieditfield(app.Panel, 'numeric');
            app.maxrEditFieldLabel = uilabel(app.Panel);
            app.maxrEditFieldLabel.Text = 'max r';
            app.maxrEditField = uieditfield(app.Panel, 'numeric');
            app.Panel_2 = uipanel(app.Panel);
            app.Panel_2.Visible = 'off';
            app.SpecifyLJParametersLabel = uilabel(app.Panel_2);
            app.SpecifyLJParametersLabel.Text = 'Specify LJ Parameters';
            app.FitPotentialTab = uitab(app.TabGroup);
            app.FitPotentialTab.Title = 'Fit Potential';
            app.ExecuteButton_2 = uibutton(app.FitPotentialTab, 'push');
            app.ExecuteButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButton_2Pushed, true);
            app.ExecuteButton_2.Text = 'Execute';
            app.SelectPotentialFileButton = uibutton(app.FitPotentialTab, 'push');
            app.SelectPotentialFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPotentialFileButtonPushed, true);
            app.SelectPotentialFileButton.Text = 'Select Potential File';
            app.SelectedPathEditFieldLabel = uilabel(app.FitPotentialTab);
            app.SelectedPathEditFieldLabel.Text = 'Selected Path';
            app.SelectedPathEditField = uieditfield(app.FitPotentialTab, 'text');
            app.DataLocationEditFieldLabel = uilabel(app.FitPotentialTab);
            app.DataLocationEditFieldLabel.Text = 'Data Location';
            app.DataLocationEditField = uieditfield(app.FitPotentialTab, 'text');
            app.OrSelectYourOwnPotentialDataFileLabel = uilabel(app.FitPotentialTab);
            app.OrSelectYourOwnPotentialDataFileLabel.Text = 'Or Select Your Own Potential Data File';
            app.FitTypeDropDownLabel = uilabel(app.FitPotentialTab);
            app.FitTypeDropDownLabel.Text = 'Fit Type';
            app.FitTypeDropDown = uidropdown(app.FitPotentialTab);
            app.FitTypeDropDown.Items = {'Select Fit Type', 'Coulomb', 'Lennard-Jones'};
            app.FitTypeDropDown.ClickedFcn = createCallbackFcn(app, @FitTypeDropDownClicked, true);
            app.FitTypeDropDown.Value = 'Coulomb';
            app.Panel_3 = uipanel(app.FitPotentialTab);
            app.SpecifyCoulombParametersLabel_2 = uilabel(app.Panel_3);
            app.SpecifyCoulombParametersLabel_2.Text = 'Specify Coulomb Parameters';
            app.Z1EditField_2Label = uilabel(app.Panel_3);
            app.Z1EditField_2Label.Text = 'Z1';
            app.Z1EditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.minrEditField_2Label = uilabel(app.Panel_3);
            app.minrEditField_2Label.Text = 'min r ';
            app.minrEditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.maxrEditField_2Label = uilabel(app.Panel_3);
            app.maxrEditField_2Label.Text = 'max r';
            app.maxrEditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.toleranceEditFieldLabel = uilabel(app.Panel_3);
            app.toleranceEditFieldLabel.Text = 'tolerance';
            app.toleranceEditField = uieditfield(app.Panel_3, 'numeric');
            app.maxZ2EditFieldLabel = uilabel(app.Panel_3);
            app.maxZ2EditFieldLabel.Text = 'max Z2';
            app.maxZ2EditField = uieditfield(app.Panel_3, 'numeric');
            app.minZ2EditFieldLabel = uilabel(app.Panel_3);
            app.minZ2EditFieldLabel.Text = 'min Z2';
            app.minZ2EditField = uieditfield(app.Panel_3, 'numeric');
            app.Panel_4 = uipanel(app.FitPotentialTab);
            app.SpecifyLJParametersLabel_2 = uilabel(app.Panel_4);
            app.SpecifyLJParametersLabel_2.Text = 'Specify LJ Parameters';
            app.TabGroup2_2 = uitabgroup(app.FitPotentialTab);
            app.LINEARTab_2 = uitab(app.TabGroup2_2);
            app.LINEARTab_2.Title = 'LINEAR';
            app.UIAxes_3 = uiaxes(app.LINEARTab_2);
            title(app.UIAxes_3, 'Fitted Interatomic Potential')
            xlabel(app.UIAxes_3, 'Interatomic Distance (Å)')
            ylabel(app.UIAxes_3, 'Energy (eV)')
            zlabel(app.UIAxes_3, 'Z')
            app.LOGTab_2 = uitab(app.TabGroup2_2);
            app.LOGTab_2.Title = 'LOG';
            app.UIAxes_4 = uiaxes(app.LOGTab_2);
            title(app.UIAxes_4, 'Fitted Interatomic Potential')
            xlabel(app.UIAxes_4, 'Interatomic Distance (Å)')
            ylabel(app.UIAxes_4, 'Energy (eV)')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.XScale = 'log';
            app.UIAxes_4.YScale = 'log';
            app.CalculateScatteringIntegralsTab = uitab(app.TabGroup);
            app.CalculateScatteringIntegralsTab.Title = 'Calculate Scattering Integrals';
            app.CalcuateTransportCoefficientsTab = uitab(app.TabGroup);
            app.CalcuateTransportCoefficientsTab.Title = 'Calcuate Transport Coefficients';
            app.CalcuateDSMCCoefficientsTab = uitab(app.TabGroup);
            app.CalcuateDSMCCoefficientsTab.Title = 'Calcuate DSMC Coefficients';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end